classdef project2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        Gauge                   matlab.ui.control.LinearGauge
        GaugeLabel              matlab.ui.control.Label
        LEDStatusLamp           matlab.ui.control.Lamp
        LEDStatusLampLabel      matlab.ui.control.Label
        MotorLabel              matlab.ui.control.Label
        LEDLabel                matlab.ui.control.Label
        UltraSoundLabel         matlab.ui.control.Label
        MotorPinEditField       matlab.ui.control.NumericEditField
        MotorPinEditFieldLabel  matlab.ui.control.Label
        LED_RPinEditField       matlab.ui.control.NumericEditField
        LED_RPinEditFieldLabel  matlab.ui.control.Label
        LED_GPinEditField       matlab.ui.control.NumericEditField
        LED_GPinEditFieldLabel  matlab.ui.control.Label
        TrigPinEditField        matlab.ui.control.NumericEditField
        TrigPinEditFieldLabel   matlab.ui.control.Label
        EchoPinEditField        matlab.ui.control.NumericEditField
        EchoPinEditFieldLabel   matlab.ui.control.Label
        CenterPanel             matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        RightPanel              matlab.ui.container.Panel
        LoadDataButton          matlab.ui.control.Button
        SaveDataButton          matlab.ui.control.Button
        PlayButton              matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        InitializeButton        matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        DataPath = 'C:\Users\Charlescai\Desktop\data.mat'
                            % Data Store/Load Path
        xData               % Heart beat data (time)
        raw_yData           % Heart beat rwa data (freq)
        filt_yData          % Heart beat filtered data (freq)
        timeFlag            % Flag for time recording
        deltaTime           % Used for loading data
        tStart              % Time recording
        Arduino             % Arudino Master Controller
        Ultrasound          % Ultrasound Module
        LEDLight            % LED Green/Red Module
        Motor               % Servo Motor Module
        Publisher           % ROS2 Publisher
        Play                % Flag for Play/Pause Button
        Plot                % Plot Handle
        initDis = 0.1       % Initial Distance
        filtWindow = 6      % Median Filter Window Size

    end
    
    methods (Access = public)
        
        % Turn on LED Green Light
        function LEDGreen(app, status)
            LEDGreenPin = strcat('D', num2str(app.LED_GPinEditField.Value));
            writeDigitalPin(app.Arduino, LEDGreenPin, status);
            if status == 1
                app.LEDStatusLamp.Color = 'green';
            end
        end
        
        % Turn off LED Red Light
        function LEDRed(app, status)
            LEDRedPin = strcat('D', num2str(app.LED_RPinEditField.Value));
            writeDigitalPin(app.Arduino, LEDRedPin, status);
            if status == 1
                app.LEDStatusLamp.Color = 'red';
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)

            try
                disp("Please waiting for Arduino to be initialized...");

                % Initialize ROS2
                rosshutdown;
                node_pub = ros2node("/publisher");
                app.Publisher = ros2publisher(node_pub, "/chatter", "std_msgs/String");

                % Get the port of Arduino
                port = serialportlist("all");

                % Throw Exception if port not found
                if port == ""
                    ME = MException('Port of Aruduino not found');
                    throw(ME);
                end

                % Init Arduino Master Controller
                app.Arduino = arduino(port,'Uno','Libraries',{'Ultrasonic', 'Servo'});
                disp("Arduino Master Controller Initialized Successfully.");
              
                % Init Ultrasound Module
                triggerPin = strcat('D', num2str(app.TrigPinEditField.Value));
                echoPin = strcat('D', num2str(app.EchoPinEditField.Value));
                app.Ultrasound = ultrasonic(app.Arduino, triggerPin, echoPin);
                disp("Ultrasound Module Initialized Successfully.");
                
                % Init Motor Module
                motorPin = strcat('D', num2str(app.MotorPinEditField.Value));
                app.Motor = servo(app.Arduino, motorPin);
                disp("Motor Module Initialized Successfully.");  

                % Init Internal Variables
                app.Play = false;
                app.timeFlag = false;
                app.deltaTime = 0;

                % Set initial parameters to be true 
                app.xData = [];         % Data for x-axis
                app.raw_yData = [];     % Data for y-axis (raw)
                app.filt_yData = [];    % Data for y-axis (filtered)
                app.Plot = plot(app.UIAxes, app.xData, app.raw_yData, app.xData, app.filt_yData);
                legend(app.UIAxes, 'Original','Filtered');
                drawnow;

            catch ME
                % Throw Error if failed
                disp(ME);
                error("Arduino Initialization failed, see above Exception for debug");                
            end
            disp("Arduino Initialization Completed Successfully!");
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            % Play when paused
            if app.Play == false

                % First time to play?
                if app.timeFlag == false
                    app.tStart = tic;   % Set start time
                    app.timeFlag = true;
                end

                % Change Play Flag
                app.Play = true;
                app.PlayButton.Text = "Pause";
                app.PlayButton.FontColor = "red";
                disp("Start the system.")
    
                while app.Play

                    % Collect raw X-Y Data
                    newY = app.Ultrasound.readDistance();
                    newX = toc(app.tStart) + app.deltaTime;
    
                    % If data collected is inf, then abandon it
                    if newY == inf
                        disp("inf");
                        
                        % Turn LED Red On and Green Off
                        LEDRed(app, 1);
                        LEDGreen(app, 0);
                        
                        continue;
                    end

                    % Add to raw data
                    app.xData = [app.xData newX];
                    app.raw_yData = [app.raw_yData newY];

                    % Filter Data using Median Filter
                    dataSize = size(app.raw_yData, 2);
                    if dataSize < app.filtWindow
                        app.filt_yData = medfilt1(app.raw_yData, dataSize);
                    else
                        app.filt_yData = medfilt1(app.raw_yData, app.filtWindow);
                    end

                    % Get filtered Data
                    filtY = app.filt_yData(end);
    
                    % Plot data
                    app.Plot = plot(app.UIAxes, app.xData, app.raw_yData, app.xData, app.filt_yData);
                    legend(app.UIAxes, 'Original','Filtered');
                    drawnow;
                    
                    % Send ROS2 Message
                    chatterMsg = ros2message(app.Publisher);
                    dis = newY - app.initDis;
                    chatterMsg.data = num2str(dis);
                    app.Publisher.send(chatterMsg);
                    fprintf("Publishing ROS messages: %s\n", chatterMsg.data);

                    
                    % Drive motor to rotate accordingly 
                    scaler = 5;
                    scaledY = filtY * scaler;
                    if scaledY <= 1 && scaledY >= 0
                        app.Motor.writePosition(scaledY);
                        app.Gauge.Value = filtY;
                    end
    
                    % Turn LED Red Off and Green On
                    LEDRed(app, 0);
                    LEDGreen(app, 1);
                end
    
                % Turn LED Red On and Green Off if system paused
                LEDRed(app, 1);
                LEDGreen(app, 0);

            else % Pause when play

                % Change Play Button
                app.Play = false;
                app.PlayButton.Text = "Play";
                app.PlayButton.FontColor = "green";
                disp("System Stopped");
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            disp("Resetting all ports for Arduino...")

            % Reset variable
            app.LEDStatusLamp.Color = 'black';
            app.Play = false;
            app.timeFlag = false;
            app.deltaTime = 0;
            app.Gauge.Value = 0;
            app.Motor.writePosition(0);

            % Clear figure
            app.xData = [];
            app.raw_yData = [];
            app.filt_yData = [];
            delete(app.Plot);
            legend(app.UIAxes, 'Original','Filtered');
            drawnow;
            cla(app.UIAxes);

            clear all;
            disp("Arduino Reset done.")
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            data = [app.xData; app.raw_yData; app.filt_yData];
            disp("Saving Data...")
            save(app.DataPath, 'data');
            disp("Data saved.")
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            try
                % Load data and draw a fig
                disp("Loading Data...");
                data = importdata(app.DataPath);
                app.xData = data(1,:);
                app.raw_yData = data(2,:);
                app.filt_yData = data(3,:);
                app.Plot = plot(app.UIAxes, app.xData, app.raw_yData, app.xData, app.filt_yData);
                legend(app.UIAxes, 'Original','Filtered');
                drawnow;
             
                % Set time flag
                app.tStart = tic;  % Set start time
                app.timeFlag = true;
                app.deltaTime = app.xData(end);
                
                disp("Data Loading Completed.");

            catch ME
                % Throw Error if failed
                disp(ME);
                error("Data Loading failed, see above Exception for debug");    
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {505, 505, 505};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {505, 505};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {189, '1x', 167};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EchoPinEditFieldLabel = uilabel(app.LeftPanel);
            app.EchoPinEditFieldLabel.Text = 'Echo Pin';
            app.EchoPinEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EchoPinEditField.Limits = [0 16];
            app.EchoPinEditField.Value = 6;
            app.TrigPinEditFieldLabel = uilabel(app.LeftPanel);
            app.TrigPinEditFieldLabel.Text = 'Trig Pin';
            app.TrigPinEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TrigPinEditField.Limits = [0 16];
            app.TrigPinEditField.Value = 8;
            app.LED_GPinEditFieldLabel = uilabel(app.LeftPanel);
            app.LED_GPinEditFieldLabel.Text = 'LED_G Pin';
            app.LED_GPinEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LED_GPinEditField.Limits = [0 16];
            app.LED_GPinEditField.Value = 3;
            app.LED_RPinEditFieldLabel = uilabel(app.LeftPanel);
            app.LED_RPinEditFieldLabel.Text = 'LED_R Pin';
            app.LED_RPinEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LED_RPinEditField.Limits = [0 16];
            app.LED_RPinEditField.Value = 2;
            app.MotorPinEditFieldLabel = uilabel(app.LeftPanel);
            app.MotorPinEditFieldLabel.Text = 'Motor Pin';
            app.MotorPinEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MotorPinEditField.Limits = [0 16];
            app.MotorPinEditField.Value = 4;
            app.UltraSoundLabel = uilabel(app.LeftPanel);
            app.UltraSoundLabel.Text = 'Ultra Sound';
            app.LEDLabel = uilabel(app.LeftPanel);
            app.LEDLabel.Text = 'LED';
            app.MotorLabel = uilabel(app.LeftPanel);
            app.MotorLabel.Text = 'Motor';
            app.LEDStatusLampLabel = uilabel(app.LeftPanel);
            app.LEDStatusLampLabel.Text = 'LED Status';
            app.LEDStatusLamp = uilamp(app.LeftPanel);
            app.GaugeLabel = uilabel(app.LeftPanel);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.LeftPanel, 'linear');
            app.Gauge.Limits = [0 1];
            app.Gauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Heart Beat')
            xlabel(app.UIAxes, 'Time / s')
            ylabel(app.UIAxes, 'Distance / m')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.InitializeButton = uibutton(app.RightPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PlayButton = uibutton(app.RightPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.SaveDataButton = uibutton(app.RightPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.LoadDataButton = uibutton(app.RightPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end